classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        DodajKryteriumButton      matlab.ui.control.Button
        MetodaDropDownLabel       matlab.ui.control.Label
        MetodaDropDown            matlab.ui.control.DropDown
        ReferencePointsDataTable  matlab.ui.control.Table
        WczytajButton             matlab.ui.control.Button
        RozmiarprbkiLabel         matlab.ui.control.Label
        RozmiarProbkiEditField    matlab.ui.control.NumericEditField
        RozkadDropDown            matlab.ui.control.DropDown
        RozkadDropDownLabel       matlab.ui.control.Label
        MinMaxDropDownLabel       matlab.ui.control.Label
        MinMaxDropDown            matlab.ui.control.DropDown
        PunktOdniesieniaLabel     matlab.ui.control.Label
        PlotButton                matlab.ui.control.Button
        RankingButton             matlab.ui.control.Button
        RankingLabel              matlab.ui.control.Label
        OutputDataTable           matlab.ui.control.Table
        ZapiszButton              matlab.ui.control.Button
        DanewejcioweLabel         matlab.ui.control.Label
        InputDataTable            matlab.ui.control.Table
        DodajButton               matlab.ui.control.Button
        EkspertDropDown           matlab.ui.control.DropDown
        EkspertDropDownLabel      matlab.ui.control.Label
        KlasaDropDownLabel        matlab.ui.control.Label
        KlasaDropDown             matlab.ui.control.DropDown
        KorektaButton             matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        ResetButton_2             matlab.ui.control.Button
        MetrykaDropDownLabel      matlab.ui.control.Label
        MetrykaDropDown           matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        Algos = containers.Map();
        ReferencePointsProp = ReferencePoints();
        InputDataProp = DataInput();
        DistribiutionType;
        DataIn % dane wejściowe wym. liczba_rekordów x liczba_kryteriów
        RangingData % dane wyjściowe wym. jak dane wejściowe `DataIn`
        allData % Description
        nonDominated % Description
        ColumnNames;
        ExpertNumbers;
        Dims = [1,35];
    end
    
    methods (Access = private)
        function saveResultsToFile(app)
            fileType = '.xlsx';
            dateTimeNow = strrep(datestr(now),' ','_');
            fileName = "results_" + strrep(dateTimeNow, ':', '-') + fileType;
            app.DataInputProp.saveToXlsx(fileName);
            app.ReferencePointsProp.saveToXlsx(fileName);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MetodaDropDown.Items = app.Algos.keys();
        end

        % Button pushed function: DodajKryteriumButton
        function DodajKryteriumButtonPushed(app, event)
            noSamples = app.RozmiarProbkiEditField.Value;
            lastSize = app.InputDataProp.getCriterionSize();
            if(lastSize==-1 || noSamples == lastSize)
                distribution = app.RozkadDropDown.Value;
                minMax = app.MinMaxDropDown.Value;
                data = [];
                result = false;
                switch distribution
                    case 'Normalny'
                            prompt = {'Parameter mu:', 'Parameter sigma:'};
                            definput = {'1', '0'};
                            answer = inputdlg(prompt, "Kryterium", app.Dims, definput);
                            result = ~isempty(answer);
                            if(result)
                                mu = str2double(answer{1});
                                sigma = str2double(answer{2});
                                map = containers.Map({'mu', 'sigma'}, [mu, sigma]); 
                                data = generate_data('Normalny', noSamples, 1, map);
                            end
                    case 'Eksponencjalny'
                            prompt = {'Parameter mu:'};
                            definput = {'1'};
                            answer = inputdlg(prompt, "Kryterium", app.Dims,definput);
                            result = ~isempty(answer);
                            if(result)    
                                mu = str2double(answer{1});
                                map = containers.Map({'mu'}, [mu]);
                                data =  generate_data('Eksponencjalny', noSamples, 1, map);
                            end
                    case 'Jednostajny'
                            result = true;
                            data  = generate_data('Jednostajny', noSamples, 1,containers.Map());
                end
                if(result)
                    newCriterion = Criterion(distribution, data, minMax);
                    app.InputDataProp = app.InputDataProp.addCriterion(newCriterion);
                    size = app.InputDataProp.getCriterionSize();
                    obj.RozmiarProbkiEditField.Value = size;
                    app.InputDataProp.setDataTable(app.InputDataTable);
                    msgbox('Pomyślnie wygenerowane dane'); 
                end
            else
                msgbox(['Poprzednii rozmiar próbki nie zgadzą się z rządanym.' ...
                    'Zmien Rozmiar próbki albo zresetuj dane.']);
            end              
        end

        % Callback function
        function RankingButtonPushed(app, event)
            algo_name = app.MetodaDropDown.Value;
            algo = app.Algos(algo_name);
            if ~isempty(algo.Params)
                dlgtitle = algo_name + " params";
                dims = [1 40];
                value = inputdlg(algo.Params.keys(),dlgtitle,dims,algo.Params.values());
                algo = algo.init(containers.Map(algo.Params.keys(), value));    
            end            
        end

        % Button pushed function: WczytajButton
        function WczytajButtonPushed(app, event)

            [file,path] = uigetfile('*.xlsx');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               filename = fullfile(path,file);
               newReferencePoints = ReferencePoints();
               newDataInput = DataInput();
               app.InputDataProp= newDataInput.initFromXlsx(filename);
               app.ReferencePointsProp=newReferencePoints.initFromXlsx(filename);
               app.ReferencePointsProp.setDataTable(app.ReferencePointsDataTable);
               app.InputDataProp.setDataTable(app.InputDataTable);
               msgbox('Pomyślnie wczytano dane');
            end
        end

        % Callback function
        function PlotButtonPushed(app, event)
            load('testData.mat','allPoints', 'x');
            app.allData = allPoints(:, :);
            app.nonDominated = x(:, :);
            figure
            scatter3(app.allData(:, 1), app.allData(:, 2), app.allData(:, 3), 'filled');
            hold on
            scatter3(app.nonDominated(:, 1), app.nonDominated(:, 2), app.nonDominated(:, 3), 'red', 'filled');
            hold on
            scatter3(min(app.nonDominated(:, 1)), min(app.nonDominated(:, 2)), min(app.nonDominated(:, 3)), 'green', 'filled');
            grid on;
            title('Scatter plot');
            xlabel('x1');
            ylabel('x2');
            zlabel('x3');
            legend('Dominated points', 'Pareto points', 'Ideal point');
        end

        % Callback function
        function GInputButtonPushed(app, event)
            
        end

        % Button pushed function: ZapiszButton
        function ZapiszButtonPushed(app, event)
            [file,path] = uiputfile('*.xlsx');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               filename = fullfile(path,file);
               app.ReferencePointsProp.saveToXlsx(filename);
               app.InputDataProp.saveToXlsx(filename);
               msgbox('Pomyślnie zapisano dane');
            end
            
        end

        % Button pushed function: DodajButton
        function DodajButtonPushed(app, event)
           if (isempty(app.InputDataTable.ColumnName))
               msgbox('Przed wygenerowaniem punktów odenisienia, wygeneruj bądź wczytaj dane');
           else     
                 definput = [];
                 data = [];
                 for j=1:length(app.InputDataTable.ColumnName)
                     definput = [definput, "0"];
                 end
                 answer = inputdlg(app.InputDataTable.ColumnName, "Nowy punk odniesienia", app.Dims,definput);
                 for i=1:length(answer)
                     data(i) = str2num(answer{i});
                 end
                 newPoint = ReferencePoint(data, str2num(app.EkspertDropDown.Value), string(app.KlasaDropDown.Value));
                 app.ReferencePointsProp = app.ReferencePointsProp.addPoint(newPoint);
                 app.ReferencePointsProp.setDataTable(app.ReferencePointsDataTable);
           end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.InputDataProp = DataInput();
            app.InputDataProp.setDataTable(app.InputDataTable);
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.ReferencePointsProp = ReferencePoints();
            app.ReferencePointsProp.setDataTable(app.ReferencePointsDataTable);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DodajKryteriumButton = uibutton(app.UIFigure, 'push');
            app.DodajKryteriumButton.ButtonPushedFcn = createCallbackFcn(app, @DodajKryteriumButtonPushed, true);
            app.DodajKryteriumButton.Text = 'Dodaj Kryterium';
            app.MetodaDropDownLabel = uilabel(app.UIFigure);
            app.MetodaDropDownLabel.Text = 'Metoda';
            app.MetodaDropDown = uidropdown(app.UIFigure);
            app.ReferencePointsDataTable = uitable(app.UIFigure);
            app.ReferencePointsDataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ReferencePointsDataTable.RowName = {};
            app.WczytajButton = uibutton(app.UIFigure, 'push');
            app.WczytajButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajButtonPushed, true);
            app.WczytajButton.Text = 'Wczytaj';
            app.RozmiarprbkiLabel = uilabel(app.UIFigure);
            app.RozmiarprbkiLabel.Text = 'Rozmiar próbki';
            app.RozmiarProbkiEditField = uieditfield(app.UIFigure, 'numeric');
            app.RozkadDropDown = uidropdown(app.UIFigure);
            app.RozkadDropDown.Items = {'Normalny', 'Eksponencjalny', 'Jednostajny'};
            app.RozkadDropDown.Value = 'Normalny';
            app.RozkadDropDownLabel = uilabel(app.UIFigure);
            app.RozkadDropDownLabel.Text = 'Rozkład';
            app.MinMaxDropDownLabel = uilabel(app.UIFigure);
            app.MinMaxDropDownLabel.Text = 'MinMax';
            app.MinMaxDropDown = uidropdown(app.UIFigure);
            app.MinMaxDropDown.Items = {'Min', 'Max'};
            app.MinMaxDropDown.Value = 'Min';
            app.PunktOdniesieniaLabel = uilabel(app.UIFigure);
            app.PunktOdniesieniaLabel.Text = 'Punkty Odniesienia';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.Text = 'Plot';
            app.RankingButton = uibutton(app.UIFigure, 'push');
            app.RankingButton.Text = 'Ranking';
            app.RankingLabel = uilabel(app.UIFigure);
            app.RankingLabel.Text = 'Ranking';
            app.OutputDataTable = uitable(app.UIFigure);
            app.OutputDataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.OutputDataTable.RowName = {};
            app.ZapiszButton = uibutton(app.UIFigure, 'push');
            app.ZapiszButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszButtonPushed, true);
            app.ZapiszButton.Text = 'Zapisz';
            app.DanewejcioweLabel = uilabel(app.UIFigure);
            app.DanewejcioweLabel.Text = 'Dane wejściowe';
            app.InputDataTable = uitable(app.UIFigure);
            app.InputDataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.InputDataTable.RowName = {};
            app.DodajButton = uibutton(app.UIFigure, 'push');
            app.DodajButton.ButtonPushedFcn = createCallbackFcn(app, @DodajButtonPushed, true);
            app.DodajButton.Text = 'Dodaj';
            app.EkspertDropDown = uidropdown(app.UIFigure);
            app.EkspertDropDown.Items = {'0', '1', '2', '3', '4', '5', ''};
            app.EkspertDropDown.Value = '0';
            app.EkspertDropDownLabel = uilabel(app.UIFigure);
            app.EkspertDropDownLabel.Text = 'Ekspert';
            app.KlasaDropDownLabel = uilabel(app.UIFigure);
            app.KlasaDropDownLabel.Text = 'Klasa';
            app.KlasaDropDown = uidropdown(app.UIFigure);
            app.KlasaDropDown.Items = {'A0', 'A1', 'A2', 'A3'};
            app.KlasaDropDown.Value = 'A0';
            app.KorektaButton = uibutton(app.UIFigure, 'push');
            app.KorektaButton.Text = 'Korekta';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ResetButton_2 = uibutton(app.UIFigure, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.MetrykaDropDownLabel = uilabel(app.UIFigure);
            app.MetrykaDropDownLabel.Text = 'Metryka';
            app.MetrykaDropDown = uidropdown(app.UIFigure);
            app.MetrykaDropDown.Items = {'L1', 'L2', 'LInf'};
            app.MetrykaDropDown.Value = 'L1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end