classdef app1_lukasz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        PlotButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        allData = zeros(100, 4) % all points, dimension is r x n, where r - number of points, n - number of criteria
        nonDominated = zeros(100, 4) % non-dominated points, dimension n_d x n, where n_d - number of Pareto points, n as above
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            load('testData.mat','allPoints', 'x');
            app.allData = allPoints(:, :);
            app.nonDominated = x(:, :);
            figure
            scatter3(app.allData(:, 1), app.allData(:, 2), app.allData(:, 3), 'filled');
            hold on
            scatter3(app.nonDominated(:, 1), app.nonDominated(:, 2), app.nonDominated(:, 3), 'red', 'filled');
            hold on
            scatter3(min(app.nonDominated(:, 1)), min(app.nonDominated(:, 2)), min(app.nonDominated(:, 3)), 'green', 'filled');
            grid on;
            title('Scatter plot');
            xlabel('x1');
            ylabel('x2');
            zlabel('x3');
            legend('Dominated points', 'Pareto points', 'Ideal point');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_lukasz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end